classdef HarvestMate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        STARTButton_2           matlab.ui.control.Button
        ESTOPButton             matlab.ui.control.StateButton
        RobotActionButtonGroup  matlab.ui.container.ButtonGroup
        DualActionButton        matlab.ui.control.ToggleButton
        SorterOnlyButton        matlab.ui.control.ToggleButton
        HarvestOnlyButton       matlab.ui.control.ToggleButton
        ProduceButtonGroup      matlab.ui.container.ButtonGroup
        MixedButton             matlab.ui.control.ToggleButton
        PotatoesButton          matlab.ui.control.ToggleButton
        TomatoesButton          matlab.ui.control.ToggleButton
        UIAxes                  matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            clear;            
            clf;
            clc;
            close all;
            % set(0,'DefaultFigureWindowStyle','docked')
            view(3)
            hold on
            axis([-2.5, 2.5, -2.5, 2.5, 0.01, 2]);
            
            
            Object = ObjectClass();
            RobotControl = RobotClass();
            Produce = ProduceClass();            
            
            disp('Initializing Environment and Robots...');

            LoadEnvironment();
            [harvesterBot, sortingBot, rightHarvester, leftHarvester, rightSorter, leftSorter] = LoadRobots();
            disp('Environment and Robots Loaded.');
        end

        % Selection changed function: ProduceButtonGroup
        function ProduceButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProduceButtonGroup.SelectedObject.Text;

            % locations for fruit to be placed when dropped
            [unsortedBoxMix,unsortedBox,goodBox,badBox] = Produce.BoxLocations();
            switch selectedButton
                case 'Tomatoes'
                    [producePositions, produceObject, produceVertices, produceTags] = ProduceClass.GenerateTomatoes();
                    disp('Generated Tomatoes.');
                case 'Potatoes'
                    [producePositions, produceObject, produceVertices, produceTags] = ProduceClass.GeneratePotatoes();
                    disp('Generated Potatoes.');
                case 'Mixed'
                    [producePositions, produceObject, produceVertices, produceTags] = ProduceClass.Mix();
                    disp('Generated Mixed Produce.');
            end   
        end

        % Callback function: ProduceButtonGroup, RobotActionButtonGroup
        function ProduceButtonGroupButtonDown(app, event)
            selectedAction = app.bgRobotAction.SelectedObject.Text;
            switch selectedAction
                case 'Harvester Only'
                    startHarvesting(producePositions, produceTags, harvesterBot, rightHarvester, leftHarvester);
                    disp('Harvester Started.');
                case 'Sorter Only'
                    startSorting(producePositions, produceTags, sortingBot, rightSorter, leftSorter);
                    disp('Sorter Started.');
                case 'Dual Action'
                    startHarvesting(producePositions, produceTags, harvesterBot, rightHarvester, leftHarvester);
                    startSorting(producePositions, produceTags, sortingBot, rightSorter, leftSorter);
                    disp('Dual Action Started.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ProduceButtonGroup = uibuttongroup(app.UIFigure);
            app.ProduceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProduceButtonGroupSelectionChanged, true);
            app.ProduceButtonGroup.Title = 'Produce';
            app.ProduceButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ProduceButtonGroupButtonDown, true);
            app.TomatoesButton = uitogglebutton(app.ProduceButtonGroup);
            app.TomatoesButton.Text = 'Tomatoes';
            app.TomatoesButton.Value = true;
            app.PotatoesButton = uitogglebutton(app.ProduceButtonGroup);
            app.PotatoesButton.Text = 'Potatoes';
            app.MixedButton = uitogglebutton(app.ProduceButtonGroup);
            app.MixedButton.Text = 'Mixed';
            app.RobotActionButtonGroup = uibuttongroup(app.UIFigure);
            app.RobotActionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProduceButtonGroupButtonDown, true);
            app.RobotActionButtonGroup.Title = 'Robot Action';
            app.HarvestOnlyButton = uitogglebutton(app.RobotActionButtonGroup);
            app.HarvestOnlyButton.Text = 'Harvest Only';
            app.HarvestOnlyButton.Value = true;
            app.SorterOnlyButton = uitogglebutton(app.RobotActionButtonGroup);
            app.SorterOnlyButton.Text = 'Sorter Only';
            app.DualActionButton = uitogglebutton(app.RobotActionButtonGroup);
            app.DualActionButton.Text = 'Dual Action';
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.Text = 'E-STOP';
            app.STARTButton_2 = uibutton(app.UIFigure, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HarvestMate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end